classdef RaceTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TrackTab                        matlab.ui.container.Tab
        LoaddrivecycleButton            matlab.ui.control.Button
        CyclenameDropDown               matlab.ui.control.DropDown
        CyclenameDropDownLabel          matlab.ui.control.Label
        CyclenameEditField              matlab.ui.control.EditField
        CyclenameEditFieldLabel         matlab.ui.control.Label
        SavedrivecycleButton            matlab.ui.control.Button
        ConvertmphtomsButton            matlab.ui.control.Button
        ConvertkmhtomsButton            matlab.ui.control.Button
        NextpointaltitudeEditField      matlab.ui.control.NumericEditField
        NextpointaltitudeEditFieldLabel  matlab.ui.control.Label
        NextpointspeedEditField         matlab.ui.control.NumericEditField
        NextpointspeedEditFieldLabel    matlab.ui.control.Label
        UpdatetableButton               matlab.ui.control.Button
        AmbienttempEditField            matlab.ui.control.NumericEditField
        AmbienttempEditFieldLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TotaltracklengthmEditField      matlab.ui.control.NumericEditField
        TotaltracklengthmEditFieldLabel  matlab.ui.control.Label
        TrackListBox                    matlab.ui.control.ListBox
        TrackListBoxLabel               matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        BikeTab                         matlab.ui.container.Tab
        CoolingConfigurationLabel       matlab.ui.control.Label
        PipenumberEditField             matlab.ui.control.NumericEditField
        PipenumberEditFieldLabel        matlab.ui.control.Label
        RadiatorpipepitchEditField      matlab.ui.control.NumericEditField
        RadiatorpipepitchEditFieldLabel  matlab.ui.control.Label
        RadiatorpipelengthEditField     matlab.ui.control.NumericEditField
        RadiatorpipelengthEditFieldLabel  matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        MassEditFieldLabel              matlab.ui.control.Label
        ChaindriveratioEditField        matlab.ui.control.NumericEditField
        ChaindriveratioEditFieldLabel   matlab.ui.control.Label
        WheelradiusEditField            matlab.ui.control.NumericEditField
        WheelradiusEditFieldLabel       matlab.ui.control.Label
        DragcoefficientEditField        matlab.ui.control.NumericEditField
        DragcoefficientEditFieldLabel   matlab.ui.control.Label
        BikefrontalareaEditField        matlab.ui.control.NumericEditField
        BikefrontalareaEditFieldLabel   matlab.ui.control.Label
        PipeODEditField                 matlab.ui.control.NumericEditField
        PipeODEditFieldLabel            matlab.ui.control.Label
        PipeIDEditField                 matlab.ui.control.NumericEditField
        PipeIDEditFieldLabel            matlab.ui.control.Label
        InvertertoradiatorpipelengthEditField  matlab.ui.control.NumericEditField
        InvertertoradiatorpipelengthEditFieldLabel  matlab.ui.control.Label
        MotortoinverterpipelengthEditField  matlab.ui.control.NumericEditField
        MotortoinverterpipelengthLabel  matlab.ui.control.Label
        RadiatortomotorpipelengthEditField  matlab.ui.control.NumericEditField
        RadiatortomotorpipelengthLabel  matlab.ui.control.Label
        InverterTypeDropDown            matlab.ui.control.DropDown
        InverterTypeDropDownLabel       matlab.ui.control.Label
        MotorTypeDropDown               matlab.ui.control.DropDown
        MotorTypeDropDownLabel          matlab.ui.control.Label
        PowerlimitDropDown              matlab.ui.control.DropDown
        PowerlimitDropDownLabel         matlab.ui.control.Label
        BikeConfigurationLabel          matlab.ui.control.Label
        VerticallayersEditField         matlab.ui.control.NumericEditField
        VerticallayersEditFieldLabel    matlab.ui.control.Label
        LongitudinalpitchEditField      matlab.ui.control.NumericEditField
        LongitudinalpitchEditFieldLabel  matlab.ui.control.Label
        LongitudinalnumberEditField     matlab.ui.control.NumericEditField
        LongitudinalnumberEditFieldLabel  matlab.ui.control.Label
        TransversepitchEditField        matlab.ui.control.NumericEditField
        TransversepitchEditFieldLabel   matlab.ui.control.Label
        TransversenumberEditField       matlab.ui.control.NumericEditField
        TransversenumberEditFieldLabel  matlab.ui.control.Label
        CelltypeDropDown                matlab.ui.control.DropDown
        CelltypeDropDownLabel           matlab.ui.control.Label
        ParallelnumberEditField         matlab.ui.control.NumericEditField
        ParallelnumberEditFieldLabel    matlab.ui.control.Label
        BatteryConfigurationLabel       matlab.ui.control.Label
        SeriesnumberEditField           matlab.ui.control.NumericEditField
        SeriesnumberEditFieldLabel      matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        XVariableDropDown_2             matlab.ui.control.DropDown
        PlotButton                      matlab.ui.control.Button
        DatanameEditField               matlab.ui.control.EditField
        DatanameEditFieldLabel          matlab.ui.control.Label
        RunthermalmodelButton           matlab.ui.control.Button
        RuncontrolmodelButton           matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        XVariableDropDown_2Label        matlab.ui.control.Label
        DatanameDropDown                matlab.ui.control.DropDown
        DatanameDropDownLabel           matlab.ui.control.Label
        LoaddataButton                  matlab.ui.control.Button
        XVariableDropDown               matlab.ui.control.DropDown
        XVariableDropDownLabel          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        clicker % Description
        lasttrack
        tabdata
        norun = 0;
        DriveCycle
        Results
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes_2,'Color','none')
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes_2)
            
            addpath('Tracks')
            %addpath('Drive_Cycles')
            addpath('Inverter_eff_maps')
            addpath('Motor_eff_maps')
            addpath('P_limit_maps')
            addpath('Cells')
            addpath('Data')
            
            app.clicker = 1;
            
            listing = dir('Data');
            for x = 1:(length(listing)-2)
                names(x) = {(listing(x+2).name)};
            end
            app.DatanameDropDown.Items = names;
            app.DatanameDropDown.Value = names(1);
            
            listing = dir('Tracks');
            for x = 1:(length(listing)-2)
                names(x) = {(listing(x+2).name)};
            end
            app.TrackListBox.Items = names;
            app.TrackListBox.Value = names(1);
            
            names = {};
            listing = dir('Point_Data');
            for x = 1:(length(listing)-2)
                names(x) = {(listing(x+2).name)};
            end
            app.CyclenameDropDown.Items = names;
            app.CyclenameDropDown.Value = names(1);
            
            names = {};
            listing = dir('Cells');
            for x = 1:(length(listing)-2)
                name = (listing(x+2).name);
                names(x) = {name(1:end-10)};
            end
            app.CelltypeDropDown.Items = names;
            app.CelltypeDropDown.Value = names(1);
            
            names = {};
            listing = dir('P_limit_maps');
            for x = 1:(length(listing)-2)
                name = (listing(x+2).name);
                names(x) = {name(1:end-10)};
            end
            app.PowerlimitDropDown.Items = names;
            app.PowerlimitDropDown.Value = names(1);
            
            names = {};
            listing = dir("Motor_eff_maps");
            for x = 1:(length(listing)-2)
                name = (listing(x+2).name);
                names(x) = {name(1:end-12)};
            end
            app.MotorTypeDropDown.Items = names;
            app.MotorTypeDropDown.Value = names(1);
            
            names = {};
            listing = dir("Inverter_eff_maps");
            for x = 1:(length(listing)-2)
                name = (listing(x+2).name);
                names(x) = {name(1:end-12)};
            end
            app.InverterTypeDropDown.Items = names;
            app.InverterTypeDropDown.Value = names(1);
            
            app.lasttrack = app.TrackListBox.Value;
            
            imshow(app.TrackListBox.Value,'Parent',app.UIAxes)%, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
            %app.UIAxes.XLim = [0, I.XData(2)];
            %app.UIAxes.YLim = [0, I.YData(2)];

            %plot(app.UIAxes,50,50,'Or')
            %app.Image.ImageSource = app.TrackListBox.Value;
            %uistack(app.UIAxes,'top')
            app.UITable.Data = zeros(3,1);
            app.tabdata = zeros(5,1);
            addpath('Tracks')
        end

        % Value changed function: TrackListBox
        function TrackListBoxValueChanged(app, event)
            answer = 'yes';
            if length(app.tabdata(1,:))>1
                answer = questdlg({'Are you sure you want to change track?'; 'Points will be reset'},'Are you sure?','yes','no ','no ');
            end
            if answer == 'yes'
                value = app.TrackListBox.Value;
                
                imshow(app.TrackListBox.Value,'Parent',app.UIAxes)%, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                %app.UIAxes.XLim = [0, I.XData(2)];
                %app.UIAxes.YLim = [0, I.YData(2)];
                
                %app.Image.ImageSource = app.TrackListBox.Value;
                app.UITable.Data = zeros(3,1);
                app.tabdata = zeros(5,1);
                app.lasttrack = app.TrackListBox.Value;
                app.clicker = 1;
                plot(app.UIAxes_2,app.tabdata(1,:),app.tabdata(2,:),'+-r','MarkerSize',8,"LineWidth",1.3)

            else
                app.TrackListBox.Value = app.lasttrack;
            end
        end

        % Button pushed function: UpdatetableButton
        function UpdatetableButtonPushed(app, event)
            app.UITable.ColumnWidth = num2cell(50*ones(1,length(app.tabdata(1,:))+1));
            pixeldist(1) = 0;
            
            for n = 2:(length(app.tabdata(1,:)))
                xdiff = app.tabdata(1,n)-app.tabdata(1,n-1);
                ydiff = app.tabdata(2,n)-app.tabdata(2,n-1);
                pixeldist(n) = ((xdiff^2)+(ydiff^2))^(0.5);
            end
            n = length(app.tabdata(1,:));
            xdiff = app.tabdata(1,1)-app.tabdata(1,n);
            ydiff = app.tabdata(2,1)-app.tabdata(2,n);
            pixeldist(n+1) = ((xdiff^2)+(ydiff^2))^(0.5);
            distance = (pixeldist/sum(pixeldist))*app.TotaltracklengthmEditField.Value;
            
            
            app.UITable.Data = [app.tabdata(3:5,:),app.tabdata(3:5,1)];
            app.UITable.Data(1,:) = cumsum(distance);
            app.UITable.Data(2,end) = app.NextpointspeedEditField.Value;
            plot(app.UIAxes_2,[app.tabdata(1,:),app.tabdata(1,1)],[app.tabdata(2,:),app.tabdata(2,1)],'+-r','MarkerSize',8,"LineWidth",1.3)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            datalength = length(app.tabdata(1,:));
            app.tabdata(indices(1)+2,indices(2)) = newData;
            app.tabdata = app.tabdata(:,1:datalength);
            app.UITable.Data(indices(1),indices(2)) = newData;
          
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            if app.norun == 0
                n = app.clicker;
                %app.UIFigure.HandleVisibility = 'callback';
            
                point = get(app.UIAxes_2,'CurrentPoint');
                
                if n>1
                    app.tabdata(:,n) = [point(2,1:2)';0;app.NextpointspeedEditField.Value;app.NextpointaltitudeEditField.Value];
                else
                    app.tabdata(:,n) = [point(2,1:2)';zeros(3,1)];
                end
    
                plot(app.UIAxes_2,app.tabdata(1,:),app.tabdata(2,:),'+-r','MarkerSize',8,"LineWidth",1.3)
                
                app.clicker = app.clicker + 1;
            else
                app.norun = 0;
            end

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            type = app.UIFigure.SelectionType;
            
            if length(type) == 3
                n = app.clicker;
                if n>1
                    app.tabdata = app.tabdata(:,1:end-1);
                    app.clicker = app.clicker - 1;
                else
                    app.tabdata = zeros(5,1);
                end
                
                plot(app.UIAxes_2,app.tabdata(1,:),app.tabdata(2,:),'+-r','MarkerSize',8,"LineWidth",1.3)
                
                app.norun = 1;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            temppoints = [app.tabdata,app.tabdata(:,1)];
            
            plot(app.UIAxes_2,[app.tabdata(1,:),app.tabdata(1,1)],[app.tabdata(2,:),app.tabdata(2,1)],'+-r','MarkerSize',8,"LineWidth",1.3)
                        
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,temppoints(1,indices(2)),temppoints(2,indices(2)),'Or','MarkerSize',30,"LineWidth",2)
            plot(app.UIAxes_2,temppoints(1,indices(2)),temppoints(2,indices(2)),'Xb','MarkerSize',30,"LineWidth",1.2)
            hold(app.UIAxes_2,'off')
            
        end

        % Button pushed function: ConvertkmhtomsButton
        function ConvertkmhtomsButtonPushed(app, event)
            app.UITable.Data(2,:) = app.UITable.Data(2,:).*(1000/(60^2));
            %app.tabdata(3:5,:) = app.UITable.Data(:,1:end-1);
        end

        % Button pushed function: ConvertmphtomsButton
        function ConvertmphtomsButtonPushed(app, event)
            app.UITable.Data(2,:) = app.UITable.Data(2,:).*0.44704;
            %app.tabdata(3:5,:) = app.UITable.Data(:,1:end-1);
        end

        % Button pushed function: SavedrivecycleButton
        function SavedrivecycleButtonPushed(app, event)
            Speed = interp1(app.UITable.Data(1,:),app.UITable.Data(2,:),0:10:app.UITable.Data(1,end),'spline');
            Alt = interp1(app.UITable.Data(1,:),app.UITable.Data(3,:),0:10:app.UITable.Data(1,end),'spline');
            app.DriveCycle = [0:10:app.UITable.Data(1,end);Speed;Alt];
            a = convertCharsToStrings(['Drive_Cycles/',app.CyclenameEditField.Value,'.mat']);
            b = app.DriveCycle;
            %assignin("base",'b',B)
            
            d = convertCharsToStrings(['Point_Data/',app.CyclenameEditField.Value,'.mat']);
            c = app.tabdata;
            %assignin("base","c",C)
            save(d,'c')
            save(a,'b')
            
        end

        % Button pushed function: LoaddrivecycleButton
        function LoaddrivecycleButtonPushed(app, event)
            load(convertCharsToStrings(['Point_Data\',app.CyclenameDropDown.Value]))
            load(convertCharsToStrings(['Drive_Cycles\',app.CyclenameDropDown.Value]))
            
            app.tabdata = c;
            app.DriveCycle = b;
            
            app.clicker = length(app.tabdata(1,:))+1;
            app.UITable.ColumnWidth = num2cell(50*ones(1,length(app.tabdata(1,:))+1));
            
            for n = 2:(length(app.tabdata(1,:)))
                xdiff = app.tabdata(1,n)-app.tabdata(1,n-1);
                ydiff = app.tabdata(2,n)-app.tabdata(2,n-1);
                pixeldist(n) = ((xdiff^2)+(ydiff^2))^(0.5);
            end
            n = length(app.tabdata(1,:));
            xdiff = app.tabdata(1,1)-app.tabdata(1,n);
            ydiff = app.tabdata(2,1)-app.tabdata(2,n);
            pixeldist(n+1) = ((xdiff^2)+(ydiff^2))^(0.5);
            distance = (pixeldist/sum(pixeldist)).*app.TotaltracklengthmEditField.Value;
            
            
            app.UITable.Data = [app.tabdata(3:5,:),app.tabdata(3:5,1)];
            app.UITable.Data(1,:) = cumsum(distance);
            app.UITable.Data(2,end) = app.NextpointspeedEditField.Value;
            plot(app.UIAxes_2,[app.tabdata(1,:),app.tabdata(1,1)],[app.tabdata(2,:),app.tabdata(2,1)],'+-r','MarkerSize',8,"LineWidth",1.3)
        end

        % Drop down opening function: CyclenameDropDown
        function CyclenameDropDownOpening(app, event)
            names = {};
            listing = dir('Point_Data');
            for x = 1:(length(listing)-2)
                names(x) = {(listing(x+2).name)};
            end
            app.CyclenameDropDown.Items = names;
        end

        % Button pushed function: RunthermalmodelButton
        function RunthermalmodelButtonPushed(app, event)
            load("result.mat")
            
            Simulation_Time = Result(1,end);
            simvar;
            
            BikeConfig.area = app.BikefrontalareaEditField.Value;
            BikeConfig.cd = app.DragcoefficientEditField.Value;
            BikeConfig.wheelrad = app.WheelradiusEditField.Value;
            BikeConfig.gear = app.ChaindriveratioEditField.Value;
            BikeConfig.SeriesNo = app.SeriesnumberEditField.Value;
            BikeConfig.ParallelNo = app.ParallelnumberEditField.Value;
            BikeConfig.mass = app.MassEditField.Value;
            
            Nt = app.TransversenumberEditField.Value;
            Nl = app.LongitudinalnumberEditField.Value;
            Nv = app.VerticallayersEditField.Value;
            
            assignin('base','ACderate',ACderate)
            assignin('base','airtemp',airtemp)
            assignin('base','BikeConfig',BikeConfig)
            assignin('base','CoreDm',CoreDm)
            assignin('base','CoreRm',CoreRm)
            assignin('base','Cpcase',Cpcase)
            assignin('base','Cpcore',Cpcore)
            assignin('base','CpIb',CpIb)
            assignin('base','CpIc',CpIc)
            assignin('base','Cpmag',Cpmag)
            assignin('base','Di',Di)
            assignin('base','Dm',Dm)
            assignin('base','Hi',Hi)
            assignin('base','La',La)
            assignin('base','Li',Li)
            assignin('base','Lw',Lw)
            assignin('base','Mc',Mc)
            assignin('base','Mcase',Mcase)
            assignin('base','MIbf',MIbf)
            assignin('base','MIbw',MIbw)
            assignin('base','MIc',MIc)
            assignin('base','Mmag',Mmag)
            assignin('base','Nt',Nt)
            assignin('base','Nl',Nl)
            assignin('base','Nv',Nv)
            assignin('base','PDi',PDi)
            assignin('base','PDm',PDm)
            assignin('base','PLi',PLi)
            assignin('base','PLm',PLm)
            assignin('base','PSplit',PSplit)
            assignin('base','Rba',Rba)
            assignin('base','Rcb',Rcb)
            assignin('base','Rcbb',Rcbb)
            assignin('base','Rcc',Rcc)
            assignin('base','Rcf',Rcf)
            assignin('base','Rm',Rm)
            assignin('base','Rmc',Rmc)
            assignin('base','Rtc',Rtc)
            assignin('base','StartTempI',StartTempI)
            assignin('base','StartTempM',StartTempM)

            
            sim('ThermalModel',Simulation_Time)
            load("result2.mat")
            Time = (Result.Time)';
            Result = (Result.Data)';
            Result = [Time;Result];
            status = 'done'
            save("Data\result2.mat","Result")
        end

        % Button pushed function: RuncontrolmodelButton
        function RuncontrolmodelButtonPushed(app, event)
            load(convertCharsToStrings(['Drive_Cycles\',app.CyclenameDropDown.Value]))
            app.DriveCycle = b;
            simvar;
            
            Result = Control(BikeConfig,app.DriveCycle,0.1);
            app.Results = Result;
            status = 'done'
            save("Data\result.mat","Result")
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            X = str2num(app.XVariableDropDown.Value);
            Y = str2num(app.XVariableDropDown_2.Value);
            
            plot(app.UIAxes2,app.Results(X,:),app.Results(Y,:))
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            load(convertCharsToStrings(['Data\',app.DatanameDropDown.Value]));
            app.Results = Result;
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            d = convertCharsToStrings(['Data/',app.DatanameEditField.Value,'.mat']);
            Result = app.Results;
            assignin("base","Result",Result)
            save(d,'Result')
            
            listing = dir('Data');
            for x = 1:(length(listing)-2)
                names(x) = {(listing(x+2).name)};
            end
            app.DatanameDropDown.Items = names;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrackTab = uitab(app.TabGroup);
            app.TrackTab.Title = 'Track';
            app.UIAxes = uiaxes(app.TrackTab);
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.TrackTab);
            app.UIAxes_2.XLim = [0 924];
            app.UIAxes_2.YLim = [0 927];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.TrackListBoxLabel = uilabel(app.TrackTab);
            app.TrackListBoxLabel.Text = 'Track';
            app.TrackListBox = uilistbox(app.TrackTab);
            app.TrackListBox.Items = {'Item 1', 'Item 5', 'Item 6', 'Item 2', 'Item 3', 'Item 4'};
            app.TrackListBox.ValueChangedFcn = createCallbackFcn(app, @TrackListBoxValueChanged, true);
            app.TotaltracklengthmEditFieldLabel = uilabel(app.TrackTab);
            app.TotaltracklengthmEditFieldLabel.Text = 'Total track length (m)';
            app.TotaltracklengthmEditField = uieditfield(app.TrackTab, 'numeric');
            app.TotaltracklengthmEditField.Value = 1000;
            app.UITable = uitable(app.TrackTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {'Distance'; 'Speed'; 'Altitude'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AmbienttempEditFieldLabel = uilabel(app.TrackTab);
            app.AmbienttempEditFieldLabel.Text = 'Ambient temp';
            app.AmbienttempEditField = uieditfield(app.TrackTab, 'numeric');
            app.AmbienttempEditField.Value = 20;
            app.UpdatetableButton = uibutton(app.TrackTab, 'push');
            app.UpdatetableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetableButtonPushed, true);
            app.UpdatetableButton.Text = 'Update table';
            app.NextpointspeedEditFieldLabel = uilabel(app.TrackTab);
            app.NextpointspeedEditFieldLabel.Text = 'Next point speed';
            app.NextpointspeedEditField = uieditfield(app.TrackTab, 'numeric');
            app.NextpointaltitudeEditFieldLabel = uilabel(app.TrackTab);
            app.NextpointaltitudeEditFieldLabel.Text = 'Next point altitude';
            app.NextpointaltitudeEditField = uieditfield(app.TrackTab, 'numeric');
            app.ConvertkmhtomsButton = uibutton(app.TrackTab, 'push');
            app.ConvertkmhtomsButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertkmhtomsButtonPushed, true);
            app.ConvertkmhtomsButton.Text = 'Convert km/h to m/s';
            app.ConvertmphtomsButton = uibutton(app.TrackTab, 'push');
            app.ConvertmphtomsButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertmphtomsButtonPushed, true);
            app.ConvertmphtomsButton.Text = 'Convert mph to m/s';
            app.SavedrivecycleButton = uibutton(app.TrackTab, 'push');
            app.SavedrivecycleButton.ButtonPushedFcn = createCallbackFcn(app, @SavedrivecycleButtonPushed, true);
            app.SavedrivecycleButton.Text = 'Save drive-cycle';
            app.CyclenameEditFieldLabel = uilabel(app.TrackTab);
            app.CyclenameEditFieldLabel.Text = 'Cycle name';
            app.CyclenameEditField = uieditfield(app.TrackTab, 'text');
            app.CyclenameEditField.Placeholder = 'Please add a name';
            app.CyclenameEditField.Value = 'Default';
            app.CyclenameDropDownLabel = uilabel(app.TrackTab);
            app.CyclenameDropDownLabel.Text = 'Cycle name';
            app.CyclenameDropDown = uidropdown(app.TrackTab);
            app.CyclenameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CyclenameDropDownOpening, true);
            app.LoaddrivecycleButton = uibutton(app.TrackTab, 'push');
            app.LoaddrivecycleButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddrivecycleButtonPushed, true);
            app.LoaddrivecycleButton.Text = 'Load drive-cycle';
            app.BikeTab = uitab(app.TabGroup);
            app.BikeTab.Title = 'Bike';
            app.SeriesnumberEditFieldLabel = uilabel(app.BikeTab);
            app.SeriesnumberEditFieldLabel.Text = 'Series number';
            app.SeriesnumberEditField = uieditfield(app.BikeTab, 'numeric');
            app.SeriesnumberEditField.Value = 100;
            app.BatteryConfigurationLabel = uilabel(app.BikeTab);
            app.BatteryConfigurationLabel.Text = 'Battery Configuration';
            app.ParallelnumberEditFieldLabel = uilabel(app.BikeTab);
            app.ParallelnumberEditFieldLabel.Text = 'Parallel number';
            app.ParallelnumberEditField = uieditfield(app.BikeTab, 'numeric');
            app.ParallelnumberEditField.Value = 14;
            app.CelltypeDropDownLabel = uilabel(app.BikeTab);
            app.CelltypeDropDownLabel.Text = 'Cell type';
            app.CelltypeDropDown = uidropdown(app.BikeTab);
            app.TransversenumberEditFieldLabel = uilabel(app.BikeTab);
            app.TransversenumberEditFieldLabel.Text = 'Transverse number';
            app.TransversenumberEditField = uieditfield(app.BikeTab, 'numeric');
            app.TransversenumberEditField.Value = 14;
            app.TransversepitchEditFieldLabel = uilabel(app.BikeTab);
            app.TransversepitchEditFieldLabel.Text = 'Transverse pitch';
            app.TransversepitchEditField = uieditfield(app.BikeTab, 'numeric');
            app.TransversepitchEditField.Value = 0.02;
            app.LongitudinalnumberEditFieldLabel = uilabel(app.BikeTab);
            app.LongitudinalnumberEditFieldLabel.Text = 'Longitudinal number';
            app.LongitudinalnumberEditField = uieditfield(app.BikeTab, 'numeric');
            app.LongitudinalnumberEditField.Value = 20;
            app.LongitudinalpitchEditFieldLabel = uilabel(app.BikeTab);
            app.LongitudinalpitchEditFieldLabel.Text = 'Longitudinal pitch';
            app.LongitudinalpitchEditField = uieditfield(app.BikeTab, 'numeric');
            app.LongitudinalpitchEditField.Value = 0.02;
            app.VerticallayersEditFieldLabel = uilabel(app.BikeTab);
            app.VerticallayersEditFieldLabel.Text = 'Vertical layers';
            app.VerticallayersEditField = uieditfield(app.BikeTab, 'numeric');
            app.VerticallayersEditField.Value = 5;
            app.BikeConfigurationLabel = uilabel(app.BikeTab);
            app.BikeConfigurationLabel.Text = 'Bike Configuration';
            app.PowerlimitDropDownLabel = uilabel(app.BikeTab);
            app.PowerlimitDropDownLabel.Text = 'Power limit';
            app.PowerlimitDropDown = uidropdown(app.BikeTab);
            app.MotorTypeDropDownLabel = uilabel(app.BikeTab);
            app.MotorTypeDropDownLabel.Text = 'MotorType';
            app.MotorTypeDropDown = uidropdown(app.BikeTab);
            app.InverterTypeDropDownLabel = uilabel(app.BikeTab);
            app.InverterTypeDropDownLabel.Text = 'InverterType';
            app.InverterTypeDropDown = uidropdown(app.BikeTab);
            app.RadiatortomotorpipelengthLabel = uilabel(app.BikeTab);
            app.RadiatortomotorpipelengthLabel.Text = {'Radiator to motor'; 'pipe length'};
            app.RadiatortomotorpipelengthEditField = uieditfield(app.BikeTab, 'numeric');
            app.RadiatortomotorpipelengthEditField.Value = 0.9;
            app.MotortoinverterpipelengthLabel = uilabel(app.BikeTab);
            app.MotortoinverterpipelengthLabel.Text = {'Motor to inverter'; 'pipe length'};
            app.MotortoinverterpipelengthEditField = uieditfield(app.BikeTab, 'numeric');
            app.MotortoinverterpipelengthEditField.Value = 0.4;
            app.InvertertoradiatorpipelengthEditFieldLabel = uilabel(app.BikeTab);
            app.InvertertoradiatorpipelengthEditFieldLabel.Text = {'Inverter to radiator '; 'pipe length'};
            app.InvertertoradiatorpipelengthEditField = uieditfield(app.BikeTab, 'numeric');
            app.InvertertoradiatorpipelengthEditField.Value = 1.1;
            app.PipeIDEditFieldLabel = uilabel(app.BikeTab);
            app.PipeIDEditFieldLabel.Text = 'Pipe ID';
            app.PipeIDEditField = uieditfield(app.BikeTab, 'numeric');
            app.PipeIDEditField.Value = 0.0125;
            app.PipeODEditFieldLabel = uilabel(app.BikeTab);
            app.PipeODEditFieldLabel.Text = 'Pipe OD';
            app.PipeODEditField = uieditfield(app.BikeTab, 'numeric');
            app.PipeODEditField.Value = 0.016;
            app.BikefrontalareaEditFieldLabel = uilabel(app.BikeTab);
            app.BikefrontalareaEditFieldLabel.Text = 'Bike frontal area';
            app.BikefrontalareaEditField = uieditfield(app.BikeTab, 'numeric');
            app.BikefrontalareaEditField.Value = 1;
            app.DragcoefficientEditFieldLabel = uilabel(app.BikeTab);
            app.DragcoefficientEditFieldLabel.Text = 'Drag coefficient';
            app.DragcoefficientEditField = uieditfield(app.BikeTab, 'numeric');
            app.DragcoefficientEditField.Value = 0.4;
            app.WheelradiusEditFieldLabel = uilabel(app.BikeTab);
            app.WheelradiusEditFieldLabel.Text = 'Wheel radius';
            app.WheelradiusEditField = uieditfield(app.BikeTab, 'numeric');
            app.WheelradiusEditField.Value = 0.33;
            app.ChaindriveratioEditFieldLabel = uilabel(app.BikeTab);
            app.ChaindriveratioEditFieldLabel.Text = 'Chain drive ratio';
            app.ChaindriveratioEditField = uieditfield(app.BikeTab, 'numeric');
            app.ChaindriveratioEditField.Value = 2.3;
            app.MassEditFieldLabel = uilabel(app.BikeTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.BikeTab, 'numeric');
            app.MassEditField.Value = 250;
            app.RadiatorpipelengthEditFieldLabel = uilabel(app.BikeTab);
            app.RadiatorpipelengthEditFieldLabel.Text = 'Radiator pipe length';
            app.RadiatorpipelengthEditField = uieditfield(app.BikeTab, 'numeric');
            app.RadiatorpipelengthEditField.Value = 0.16;
            app.RadiatorpipepitchEditFieldLabel = uilabel(app.BikeTab);
            app.RadiatorpipepitchEditFieldLabel.Text = 'Radiator pipe pitch';
            app.RadiatorpipepitchEditField = uieditfield(app.BikeTab, 'numeric');
            app.RadiatorpipepitchEditField.Value = 0.009;
            app.PipenumberEditFieldLabel = uilabel(app.BikeTab);
            app.PipenumberEditFieldLabel.Text = 'Pipe number';
            app.PipenumberEditField = uieditfield(app.BikeTab, 'numeric');
            app.PipenumberEditField.Value = 13;
            app.CoolingConfigurationLabel = uilabel(app.BikeTab);
            app.CoolingConfigurationLabel.Text = 'Cooling Configuration';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes2 = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.XVariableDropDownLabel = uilabel(app.ResultsTab);
            app.XVariableDropDownLabel.Text = 'X Variable';
            app.XVariableDropDown = uidropdown(app.ResultsTab);
            app.XVariableDropDown.Items = {'Time', 'Drive cycle speed', 'Bike speed', 'Distance', 'Motor torque', 'Motor speed', 'At wheel power', 'Inverter power', 'Battery power', 'Loss motor', 'Loss Inverter', 'Loss battery', 'Energy out', 'Battery SoC', 'Front brake force', 'Motor temp', 'Inverter temp', 'Battery temp', 'Coolant temp lowest', 'Coolant temp highest'};
            app.XVariableDropDown.ItemsData = {'1', '20', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.XVariableDropDown.Value = '1';
            app.LoaddataButton = uibutton(app.ResultsTab, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.DatanameDropDownLabel = uilabel(app.ResultsTab);
            app.DatanameDropDownLabel.Text = 'Data name';
            app.DatanameDropDown = uidropdown(app.ResultsTab);
            app.DatanameDropDown.Items = {'Default', 'Option 2', 'Option 3', 'Option 4'};
            app.DatanameDropDown.Value = 'Default';
            app.XVariableDropDown_2Label = uilabel(app.ResultsTab);
            app.XVariableDropDown_2Label.Text = 'Y Variable';
            app.SavedataButton = uibutton(app.ResultsTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.RuncontrolmodelButton = uibutton(app.ResultsTab, 'push');
            app.RuncontrolmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RuncontrolmodelButtonPushed, true);
            app.RuncontrolmodelButton.Text = 'Run control model';
            app.RunthermalmodelButton = uibutton(app.ResultsTab, 'push');
            app.RunthermalmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunthermalmodelButtonPushed, true);
            app.RunthermalmodelButton.Text = 'Run thermal model';
            app.DatanameEditFieldLabel = uilabel(app.ResultsTab);
            app.DatanameEditFieldLabel.Text = 'Data name';
            app.DatanameEditField = uieditfield(app.ResultsTab, 'text');
            app.PlotButton = uibutton(app.ResultsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.XVariableDropDown_2 = uidropdown(app.ResultsTab);
            app.XVariableDropDown_2.Items = {'Time', 'Drive cycle speed', 'Bike speed', 'Distance', 'Motor torque', 'Motor speed', 'At wheel power', 'Inverter power', 'Battery power', 'Loss motor', 'Loss Inverter', 'Loss battery', 'Energy out', 'Battery SoC', 'Front brake force', 'Motor temp', 'Inverter temp', 'Battery temp', 'Coolant temp lowest', 'Coolant temp highest'};
            app.XVariableDropDown_2.ItemsData = {'1', '20', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.XVariableDropDown_2.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RaceTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end